classdef ForcePractice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForcePracticeUIFigure       matlab.ui.Figure
        GaugeLabel                  matlab.ui.control.Label
        Gauge                       matlab.ui.control.SemicircularGauge
        Gauge_2Label                matlab.ui.control.Label
        Gauge_2                     matlab.ui.control.LinearGauge
        RUNButton                   matlab.ui.control.Button
        IDLEButton                  matlab.ui.control.Button
        SAVEButton                  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SaveFileNameEditFieldLabel  matlab.ui.control.Label
        SaveFileNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Tactx % Description
    end
    
    methods (Access = private)
        
        function scansAvailableFunction(app, src, evt)
            
            app.Tactx.scansAvailableFunction(src, evt);
            
            if length(app.Tactx.ForceSensor.ForceTorque) > 100
                
                app.Gauge.Value = abs(mean(app.Tactx.ForceSensor.ForceTorque(end-100:end, 3)));
            else
                app.Gauge.Value = 0;
            end
            if app.Gauge.Value >= 0.45 && app.Gauge.Value <= 0.55
                
                app.Gauge_2.Value = app.Gauge_2.Value + 1;
                app.Gauge_2.ScaleColorLimits(2) = app.Gauge_2.ScaleColorLimits(2) + 1;
                
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Label.Text = "......";
            init;
            app.Tactx = TacTX('State', IdleState, 'Config', CONFIG);
            app.Tactx.NIHandler.ScansAvailableFunction = @(src, evt) app.scansAvailableFunction(src, evt);
            app.Label.Text = "Connected to DAQ devices!";
            
            
        end

        % Close request function: ForcePracticeUIFigure
        function ForcePracticeUIFigureCloseRequest(app, event)
            
            app.Tactx.idle();
            app.Tactx.save(app.SaveFileNameEditField.Value);
            app.Tactx.idle();
            delete(app)
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.Tactx.run();
            app.Label.Text = "Data Acquisition started!";
            disp(app.Tactx.State);
        end

        % Button pushed function: IDLEButton
        function IDLEButtonPushed(app, event)
            app.Tactx.idle();
            app.Label.Text = "System is IDLE!";
            disp(app.Tactx.State);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.Tactx.save(app.SaveFileNameEditField.Value);
            app.Label.Text = "Data is saved!";
            disp(app.Tactx.State);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForcePracticeUIFigure = uifigure('Visible', 'off');
            app.ForcePracticeUIFigure.Name = 'Force Practice';
            app.ForcePracticeUIFigure.CloseRequestFcn = createCallbackFcn(app, @ForcePracticeUIFigureCloseRequest, true);
            app.GaugeLabel = uilabel(app.ForcePracticeUIFigure);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.ForcePracticeUIFigure, 'semicircular');
            app.Gauge.Limits = [0 1];
            app.Gauge.MajorTicks = [0 0.1 0.2 0.3 0.4 0.45 0.5 0.55 0.6 0.7 0.8 0.9 1];
            app.Gauge_2Label = uilabel(app.ForcePracticeUIFigure);
            app.Gauge_2Label.Text = '';
            app.Gauge_2 = uigauge(app.ForcePracticeUIFigure, 'linear');
            app.Gauge_2.Value = 0.001;
            app.RUNButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.IDLEButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.IDLEButton.ButtonPushedFcn = createCallbackFcn(app, @IDLEButtonPushed, true);
            app.IDLEButton.Text = 'IDLE';
            app.SAVEButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.Label = uilabel(app.ForcePracticeUIFigure);
            app.Label.Text = '';
            app.SaveFileNameEditFieldLabel = uilabel(app.ForcePracticeUIFigure);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name';
            app.SaveFileNameEditField = uieditfield(app.ForcePracticeUIFigure, 'text');
            % Show the figure after all components are created
            app.ForcePracticeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForcePractice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForcePracticeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForcePracticeUIFigure)
        end
    end
end