classdef acc_sensor_read_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InitializeDeviceButton  matlab.ui.control.Button
        InitializePortsButton   matlab.ui.control.Button
        StartMeasurementButton  matlab.ui.control.Button
        StopMeasurementButton   matlab.ui.control.Button
        ActiveDAQLampLabel      matlab.ui.control.Label
        ActiveDAQLamp           matlab.ui.control.Lamp
        ActivePortsLampLabel    matlab.ui.control.Label
        ActivePortsLamp         matlab.ui.control.Lamp
        MeasurementLampLabel    matlab.ui.control.Label
        MeasurementLamp         matlab.ui.control.Lamp
        bxSliderLabel           matlab.ui.control.Label
        bxSlider                matlab.ui.control.Slider
        bySliderLabel           matlab.ui.control.Label
        bySlider                matlab.ui.control.Slider
        bzSliderLabel           matlab.ui.control.Label
        bzSlider                matlab.ui.control.Slider
        sxSliderLabel           matlab.ui.control.Label
        sxSlider                matlab.ui.control.Slider
        sySliderLabel           matlab.ui.control.Label
        sySlider                matlab.ui.control.Slider
        szSliderLabel           matlab.ui.control.Label
        szSlider                matlab.ui.control.Slider
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MeasurementDevice
        RandTimer          % Timer object
        PlotLine           % Line object
        PlotLine2           % Line object
        GainMatrix
    end
    
    methods (Access = private)
        
        function TimerFcn(app,~,~)
            
            data = read(app.MeasurementDevice, seconds(0.01), "OutputFormat","Matrix");
            acc = (data - [app.bxSlider.Value app.bySlider.Value app.bzSlider.Value])./[app.sxSlider.Value app.sySlider.Value app.szSlider.Value];
            
            for k = 1:3
                prevData = app.PlotLine(k).YData;
                prevData = circshift(prevData, size(data, 1));
                prevData(1:size(data, 1)) = data(:, k);
                app.PlotLine(k).YData = prevData;
                
                prevData2 = app.PlotLine2(k).YData;
                prevData2 = circshift(prevData2, size(data, 1));
                prevData2(1:size(data, 1)) = acc(:, k);
                app.PlotLine2(k).YData = prevData2;
            end
        end
        
        
        function stopTimer(app)
            % Stop the timer
            stop(app.RandTimer);
            app.MeasurementLamp.Color = [1 0 0];
        end
        
        function startTimer(app)
            %             app.MeasurementDevice.Rate = 100;
            app.MeasurementLamp.Color = [0 1 0];
            
            % If timer is not running, start it
            if strcmp(app.RandTimer.Running, 'off')
                start(app.RandTimer);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            app.ActiveDAQLamp.Color = [1 0 0];
            app.ActivePortsLamp.Color = [1 0 0];
            app.MeasurementLamp.Color = [1 0 0];
            
            
            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,zeros(1000, 3));
            legend(app.UIAxes, {"G1", "G2", "G3"});
            
            % Initial plot is all zeros
            app.PlotLine2 = plot(app.UIAxes2,zeros(1000, 3));
            legend(app.UIAxes2, {"ax", "ay", "az"});


            %  Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1/50, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.TimerFcn);      % Specify callback function
            
            app.InitializePortsButton.Enable = false;
            app.InitializePortsButton.Enable = false;
            app.StartMeasurementButton.Enable = false;
            app.StopMeasurementButton.Enable = false;
            
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
            
            app.startTimer();
            
        end

        % Button pushed function: StopMeasurementButton
        function StopMeasurementButtonPushed(app, event)
            
            app.stopTimer();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.RandTimer);
            delete(app.RandTimer);
            delete(app)
            
        end

        % Button pushed function: InitializeDeviceButton
        function InitializeDeviceButtonPushed(app, event)
            
            app.MeasurementDevice = daq("ni");
            app.ActiveDAQLamp.Color = [0 1 0];
            app.InitializePortsButton.Enable = true;
            
        end

        % Button pushed function: InitializePortsButton
        function InitializePortsButtonPushed(app, event)
            % Add channels and set channel properties, if any.
            app.MeasurementDevice.Rate = 1000;
            
            addinput(app.MeasurementDevice,"Dev2","ai4","Voltage");
            addinput(app.MeasurementDevice,"Dev2","ai12","Voltage");
            addinput(app.MeasurementDevice,"Dev2","ai5","Voltage");
            app.MeasurementDevice.Channels(1).TerminalConfig = "SingleEnded";
            app.MeasurementDevice.Channels(2).TerminalConfig = "SingleEnded";
            app.MeasurementDevice.Channels(3).TerminalConfig = "SingleEnded";
            
            app.ActivePortsLamp.Color = [0 1 0];
            
            app.StartMeasurementButton.Enable = true;
            app.StopMeasurementButton.Enable = true;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InitializeDeviceButton = uibutton(app.UIFigure, 'push');
            app.InitializeDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDeviceButtonPushed, true);
            app.InitializeDeviceButton.Text = 'Initialize Device';
            app.InitializePortsButton = uibutton(app.UIFigure, 'push');
            app.InitializePortsButton.ButtonPushedFcn = createCallbackFcn(app, @InitializePortsButtonPushed, true);
            app.InitializePortsButton.Text = 'Initialize Ports';
            app.StartMeasurementButton = uibutton(app.UIFigure, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.StopMeasurementButton = uibutton(app.UIFigure, 'push');
            app.StopMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasurementButtonPushed, true);
            app.StopMeasurementButton.Text = 'Stop Measurement';
            app.ActiveDAQLampLabel = uilabel(app.UIFigure);
            app.ActiveDAQLampLabel.Text = 'Active DAQ';
            app.ActiveDAQLamp = uilamp(app.UIFigure);
            app.ActivePortsLampLabel = uilabel(app.UIFigure);
            app.ActivePortsLampLabel.Text = 'Active Ports';
            app.ActivePortsLamp = uilamp(app.UIFigure);
            app.MeasurementLampLabel = uilabel(app.UIFigure);
            app.MeasurementLampLabel.Text = 'Measurement';
            app.MeasurementLamp = uilamp(app.UIFigure);
            app.bxSliderLabel = uilabel(app.UIFigure);
            app.bxSliderLabel.Text = {'bx'; ''};
            app.bxSlider = uislider(app.UIFigure);
            app.bxSlider.Limits = [1.5 1.9];
            app.bxSlider.Value = 1.6571;
            app.bySliderLabel = uilabel(app.UIFigure);
            app.bySliderLabel.Text = 'by';
            app.bySlider = uislider(app.UIFigure);
            app.bySlider.Limits = [1.5 1.9];
            app.bySlider.Value = 1.6695;
            app.bzSliderLabel = uilabel(app.UIFigure);
            app.bzSliderLabel.Text = 'bz';
            app.bzSlider = uislider(app.UIFigure);
            app.bzSlider.Limits = [1.5 1.9];
            app.bzSlider.Value = 1.6571;
            app.sxSliderLabel = uilabel(app.UIFigure);
            app.sxSliderLabel.Text = 'sx';
            app.sxSlider = uislider(app.UIFigure);
            app.sxSlider.Limits = [0.3 0.36];
            app.sxSlider.Value = 0.3168;
            app.sySliderLabel = uilabel(app.UIFigure);
            app.sySliderLabel.Text = 'sy';
            app.sySlider = uislider(app.UIFigure);
            app.sySlider.Limits = [0.3 0.36];
            app.sySlider.Value = 0.33;
            app.szSliderLabel = uilabel(app.UIFigure);
            app.szSliderLabel.Text = 'sz';
            app.szSlider = uislider(app.UIFigure);
            app.szSlider.Limits = [0.3 0.36];
            app.szSlider.Value = 0.33;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Voltages (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 2];
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Forces (N)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-2 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acc_sensor_read_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end