classdef basic_symbol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GaugeLabel  matlab.ui.control.Label
        Gauge       matlab.ui.control.SemicircularGauge
    end

    
    properties (Access = private)
        dev % Description
        Gain % Description
        Bias % Description
    end
    
    methods (Access = private)
        
        function logData(app, ~, ~)
            
            data = read(app.dev, app.dev.ScansAvailableFcnCount, 'OutputFormat', 'Matrix');
            force = (app.Gain * (data - app.Bias)')';
            app.Gauge.Value = abs(mean(force(:, 3)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dev = daq("ni");
            %% Add Channels
            % Add channels and set channel properties, if any.
            
            addinput(app.dev,"Dev1","ai0","Voltage");
            addinput(app.dev,"Dev1","ai1","Voltage");
            addinput(app.dev,"Dev1","ai2","Voltage");
            addinput(app.dev,"Dev1","ai3","Voltage");
            addinput(app.dev,"Dev1","ai4","Voltage");
            addinput(app.dev,"Dev1","ai5","Voltage");
            
            
            %% Read Data to bias sensor
            % Read the data in timetable format.
            app.dev.Rate = 1000;
            app.dev.ScansAvailableFcn =@(src, evt) app.logData(src, evt);
            app.dev.ScansAvailableFcnCount = 100;
            
            
            app.Gain = [-0.008988558	0.01636383	-0.016207209	1.709100556	-0.010353274	-1.714581856
                0.064670016	-2.188230603	-0.010510775	0.972092909	-0.015269682	1.041792839
                1.805711459	0.007669575	1.526170728	-0.045459199	1.933470965	-0.025708347
                0.306797685	-13.27323888	8.678856758	5.656698914	-11.02508507	6.469202187
                -11.5890942	-0.100412191	5.1181421	-10.5413148	6.505246556	10.32580521
                0.136561278	-7.586013116	0.21378772	-7.246447361	-0.092033948	-7.061610822];
            
            app.Bias = [-2.14081329345703	0.775222778320313	-1.37473144531250	-2.13544647216797	-0.570702209472656	-0.245615539550781];
            
            start(app.dev, 'continuous');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_symbol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end