classdef force_sensor_z_gauge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InitializeDeviceButton  matlab.ui.control.Button
        InitializePortsButton   matlab.ui.control.Button
        BiasButton              matlab.ui.control.Button
        StartMeasurementButton  matlab.ui.control.Button
        StopMeasurementButton   matlab.ui.control.Button
        ActiveDAQLampLabel      matlab.ui.control.Label
        ActiveDAQLamp           matlab.ui.control.Lamp
        ActivePortsLampLabel    matlab.ui.control.Label
        ActivePortsLamp         matlab.ui.control.Lamp
        MeasurementLampLabel    matlab.ui.control.Label
        MeasurementLamp         matlab.ui.control.Lamp
        GaugeLabel              matlab.ui.control.Label
        Gauge                   matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        MeasurementDevice
        RandTimer          % Timer object
        Bias % Bias vector for the measurements
        GainMatrix
    end
    
    methods (Access = private)
        
        function TimerFcn(app,~,~)
            
            data = read(app.MeasurementDevice, seconds(1/50), "OutputFormat","Matrix");
            data_ = data - app.Bias;
            force = (app.GainMatrix * data_')';
            app.Gauge.Value = abs(mean(force(:, 3)));
        end
        
        
        function stopTimer(app)
            % Stop the timer
            stop(app.RandTimer);
            app.MeasurementLamp.Color = [1 0 0];
        end
        
        function startTimer(app)
            app.MeasurementDevice.Rate = 1000;
            
            app.MeasurementLamp.Color = [0 1 0];
            
            % If timer is not running, start it
            if strcmp(app.RandTimer.Running, 'off')
                start(app.RandTimer);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.GainMatrix = [-0.008988558	0.01636383	-0.016207209	1.709100556	-0.010353274	-1.714581856
                0.064670016	-2.188230603	-0.010510775	0.972092909	-0.015269682	1.041792839
                1.805711459	0.007669575	1.526170728	-0.045459199	1.933470965	-0.025708347
                0.306797685	-13.27323888	8.678856758	5.656698914	-11.02508507	6.469202187
                -11.5890942	-0.100412191	5.1181421	-10.5413148	6.505246556	10.32580521
                0.136561278	-7.586013116	0.21378772	-7.246447361	-0.092033948	-7.061610822];
            
            %
            %             app. GainMatrix=[-0.00245   0.02466   0.05585  -0.83458  -0.04315   0.85113;
            %                 -0.02502   0.92010   0.01425  -0.45519   0.02270  -0.51853 ;
            %                 0.95748   0.03776   0.92538   0.04615   0.98824   0.03969;
            %                 -0.34745   5.54086   5.35949  -2.47291  -5.19643  -3.34585;
            %                 -6.00849  -0.40893   2.53702   5.20801   3.58517  -5.02455 ;
            %                 -0.18358   3.37833  -0.18652   3.33038  -0.09424   3.68362];
            %
            
            
            app.Bias = zeros(1,6);
            
            app.ActiveDAQLamp.Color = [1 0 0];
            app.ActivePortsLamp.Color = [1 0 0];
            app.MeasurementLamp.Color = [1 0 0];


            %  Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1/50, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.TimerFcn);      % Specify callback function
            
            app.InitializePortsButton.Enable = false;
            app.InitializePortsButton.Enable = false;
            app.StartMeasurementButton.Enable = false;
            app.StopMeasurementButton.Enable = false;
            app.BiasButton.Enable = false;
            
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
            
            app.BiasButton.Enable = false;
            app.startTimer();
            
        end

        % Button pushed function: StopMeasurementButton
        function StopMeasurementButtonPushed(app, event)
            
            app.stopTimer();
            app.BiasButton.Enable = true;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.RandTimer);
            delete(app.RandTimer);
            delete(app)
            
        end

        % Button pushed function: InitializeDeviceButton
        function InitializeDeviceButtonPushed(app, event)
            
            app.MeasurementDevice = daq("ni");
            app.ActiveDAQLamp.Color = [0 1 0];
            app.InitializePortsButton.Enable = true;
            
        end

        % Button pushed function: InitializePortsButton
        function InitializePortsButtonPushed(app, event)
            % Add channels and set channel properties, if any.
            
            addinput(app.MeasurementDevice,"Dev1","ai0","Voltage");
            addinput(app.MeasurementDevice,"Dev1","ai1","Voltage");
            addinput(app.MeasurementDevice,"Dev1","ai2","Voltage");
            addinput(app.MeasurementDevice,"Dev1","ai3","Voltage");
            addinput(app.MeasurementDevice,"Dev1","ai4","Voltage");
            addinput(app.MeasurementDevice,"Dev1","ai5","Voltage");
            
            app.ActivePortsLamp.Color = [0 1 0];
            
            app.StartMeasurementButton.Enable = true;
            app.StopMeasurementButton.Enable = true;
            
        end

        % Button pushed function: BiasButton
        function BiasButtonPushed(app, event)
            
            data = read(app.MeasurementDevice, seconds(1), "OutputFormat","Matrix");
            app.Bias = mean(data, 1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InitializeDeviceButton = uibutton(app.UIFigure, 'push');
            app.InitializeDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDeviceButtonPushed, true);
            app.InitializeDeviceButton.Text = 'Initialize Device';
            app.InitializePortsButton = uibutton(app.UIFigure, 'push');
            app.InitializePortsButton.ButtonPushedFcn = createCallbackFcn(app, @InitializePortsButtonPushed, true);
            app.InitializePortsButton.Text = 'Initialize Ports';
            app.BiasButton = uibutton(app.UIFigure, 'push');
            app.BiasButton.ButtonPushedFcn = createCallbackFcn(app, @BiasButtonPushed, true);
            app.BiasButton.Text = 'Bias';
            app.StartMeasurementButton = uibutton(app.UIFigure, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.StopMeasurementButton = uibutton(app.UIFigure, 'push');
            app.StopMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasurementButtonPushed, true);
            app.StopMeasurementButton.Text = 'Stop Measurement';
            app.ActiveDAQLampLabel = uilabel(app.UIFigure);
            app.ActiveDAQLampLabel.Text = 'Active DAQ';
            app.ActiveDAQLamp = uilamp(app.UIFigure);
            app.ActivePortsLampLabel = uilabel(app.UIFigure);
            app.ActivePortsLampLabel.Text = 'Active Ports';
            app.ActivePortsLamp = uilamp(app.UIFigure);
            app.MeasurementLampLabel = uilabel(app.UIFigure);
            app.MeasurementLampLabel.Text = 'Measurement';
            app.MeasurementLamp = uilamp(app.UIFigure);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = force_sensor_z_gauge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end