classdef RandomSignalGenerationandTransmission < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ForceGauge      matlab.ui.control.SemicircularGauge
        VelocityGauge   matlab.ui.control.LinearGauge
        InfoLabel       matlab.ui.control.Label
        GENERATEButton  matlab.ui.control.Button
        EMITButton      matlab.ui.control.Button
        SAVEButton      matlab.ui.control.Button
        EditField       matlab.ui.control.NumericEditField
        Lamp            matlab.ui.control.Lamp
        SAVEButton_2    matlab.ui.control.Button
        SignalAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Signal % Description
        SignalLine % Description
        SignalLength
        Tactx % Description
        Length
        Speed
        
    end
    
    methods (Access = private)
        
        function scansAvailableFunction(app, src, evt)
            
            % Actual callback
            app.Tactx.scansAvailableFunction(src, evt);
            
            
            if length(app.Tactx.ForceSensor.ForceTorque) > 100
                
                app.ForceGauge.Value = abs(mean(app.Tactx.ForceSensor.ForceTorque(end-100:end, 3)));
                
            else
                
                app.ForceGauge.Value = 0;
                
            end
            
            app.VelocityGauge.Value = app.VelocityGauge.Value + (app.VelocityGauge.Limits(2)-app.VelocityGauge.Limits(1))/( (app.Tactx.NIHandler.Rate / app.Tactx.Config.SCANS_AVAILABLE_FUNCTION_COUNT) * app.Length / app.Speed);
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.InfoLabel.Text = "......";
            app.Lamp.Color = [1 0 0];
            
            initRandomMeasurement;
            app.Tactx = TacTX('State', IdleState, 'Config', CONFIG);
            
            app.Tactx.NIHandler.ScansAvailableFunction = @(src, evt) app.scansAvailableFunction(src, evt);
            app.InfoLabel.Text = "Connected to DAQ devices!";
            app.Lamp.Color = [1 1 0];
            
            app.Length = 12;
            app.Speed = app.Tactx.Config.DESIRED_SLIDING_VELOCITY;
            
            app.ForceGauge.Limits = [0 2 * app.Tactx.Config.DESIRED_NORMAL_FORCE];
            forceMargin = app.Tactx.Config.DESIRED_NORMAL_FORCE / 10;
            app.ForceGauge.ScaleColorLimits = [app.Tactx.Config.DESIRED_NORMAL_FORCE - forceMargin, app.Tactx.Config.DESIRED_NORMAL_FORCE + forceMargin;
                app.Tactx.Config.DESIRED_NORMAL_FORCE + forceMargin, 2 * app.Tactx.Config.DESIRED_NORMAL_FORCE;
                0, app.Tactx.Config.DESIRED_NORMAL_FORCE - forceMargin];
            
            app.SignalLength = (app.Length / app.Speed) * app.Tactx.NIHandler.Rate;
            app.SignalLine = plot(app.SignalAxes, 0);
            app.Signal = idinput([30000000, 1], 'prbs', [0 1/200], [-3 3]);
            
            
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            
            signal_ = app.Signal(app.EditField.Value*app.SignalLength + 1 : (app.EditField.Value+1)*app.SignalLength + 1);
            
            app.SignalLine.YData = signal_;
            app.Tactx.SignalGenerator.Signal = signal_;
            
            app.InfoLabel.Text = "Data is generated!";
            
        end

        % Button pushed function: EMITButton
        function EMITButtonPushed(app, event)
            app.Tactx.run();
            app.Lamp.Color = [0 1 0];
            
            app.InfoLabel.Text = "Data Acquisition started!";
            app.EditField.Value = app.EditField.Value + 1;
            
            pause(3.5);
            
            app.Tactx.idle();
            app.Lamp.Color = [1 1 0];
            app.InfoLabel.Text = "Data Acquisition finished!";
            
            pause(3.0);
            
            app.VelocityGauge.Value = 0;
            app.ForceGauge.Value = 0;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.InfoLabel.Text = "Data saving started!";
            
            app.Tactx.idle();
            datestr_ = strrep(strrep(strrep(datestr(datetime('now')), ' ', ''), '-', ''), ':', '');
            app.Tactx.save(['deneme_' num2str(app.EditField.Value) '_' num2str(app.Speed) '_' datestr_ 'GOOD.mat']);
            app.Lamp.Color = [1 0 0];
            pause(1.0)
            app.InfoLabel.Text = "Data saving finished!";
            
            app.Tactx.idle();
            app.Lamp.Color = [1 1 0];
        end

        % Button pushed function: SAVEButton_2
        function SAVEButton_2Pushed(app, event)
            app.InfoLabel.Text = "Data saving started!";
            
            app.Tactx.idle();
            datestr_ = strrep(strrep(strrep(datestr(datetime('now')), ' ', ''), '-', ''), ':', '');
            app.Tactx.save(['deneme_' num2str(app.EditField.Value) '_' num2str(app.Speed) '_' datestr_ 'BAD.mat']);
            app.Lamp.Color = [1 0 0];
            pause(1.0)
            app.InfoLabel.Text = "Data saving finished!";
            
            app.Tactx.idle();
            app.Lamp.Color = [1 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceGauge = uigauge(app.UIFigure, 'semicircular');
            app.ForceGauge.Limits = [0 1];
            app.ForceGauge.MajorTicks = [0 0.1 0.2 0.3 0.4 0.45 0.5 0.55 0.6 0.7 0.8 0.9 1];
            app.VelocityGauge = uigauge(app.UIFigure, 'linear');
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '';
            app.GENERATEButton = uibutton(app.UIFigure, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.EMITButton = uibutton(app.UIFigure, 'push');
            app.EMITButton.ButtonPushedFcn = createCallbackFcn(app, @EMITButtonPushed, true);
            app.EMITButton.Text = 'EMIT';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp = uilamp(app.UIFigure);
            app.SAVEButton_2 = uibutton(app.UIFigure, 'push');
            app.SAVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_2Pushed, true);
            app.SAVEButton_2.Text = 'SAVE';
            app.SignalAxes = uiaxes(app.UIFigure);
            xlabel(app.SignalAxes, 'Datapoint')
            ylabel(app.SignalAxes, 'Signal')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RandomSignalGenerationandTransmission

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end