classdef Experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ForceGauge        matlab.ui.control.SemicircularGauge
        VelocityGauge     matlab.ui.control.LinearGauge
        InfoLabel         matlab.ui.control.Label
        STARTButton       matlab.ui.control.Button
        SAVEButton        matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        SAVEButton_2      matlab.ui.control.Button
        TrialLabel        matlab.ui.control.Label
        TrialNumberLabel  matlab.ui.control.Label
        CountdownLabel    matlab.ui.control.Label
        SignalAxes        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Signal % Description
        SignalLine % Description
        SignalLength
        Tactx % Description
        Length
        Speed
        Counter
    end

    methods (Access = private)

        function scansAvailableFunction(app, src, evt)

            % Actual callback
            app.Tactx.scansAvailableFunction(src, evt);


            if length(app.Tactx.ForceSensor.ForceTorque) > 100

                app.ForceGauge.Value = abs(mean(app.Tactx.ForceSensor.ForceTorque(end-100:end, 3)));

            else

                app.ForceGauge.Value = 0;

            end

            app.VelocityGauge.Value = app.VelocityGauge.Value + (app.VelocityGauge.Limits(2)-app.VelocityGauge.Limits(1))/( (app.Tactx.NIHandler.Rate / app.Tactx.Config.SCANS_AVAILABLE_FUNCTION_COUNT) * app.Tactx.Config.SLIDING_DISTANCE / app.Tactx.Config.SLIDING_VELOCITY(app.Counter));

        end


        function refreshCounterDependentProperties(app)
            
           app.refreshTrialNumberLabel(app.Counter);
            

            app.Signal = app.Tactx.Config.SIGNAL{app.Counter};
            app.refreshSignalAxes(app.Signal);
            app.Tactx.SignalGenerator.Signal = app.Signal;
            
            app.refreshForceGauge(app.Tactx.Config.NORMAL_FORCE(app.Counter));
            
            
        end


        function refreshForceGauge(app, desiredNormalForce)

            app.ForceGauge.Limits = [0 2 * desiredNormalForce];
            forceMargin = desiredNormalForce / 10;
            app.ForceGauge.ScaleColorLimits = [desiredNormalForce - forceMargin, desiredNormalForce + forceMargin;
                desiredNormalForce + forceMargin, 2 * desiredNormalForce;
                0, desiredNormalForce - forceMargin];
            app.ForceGauge.MajorTicksMode = 'auto';
 
        end

        function refreshSignalAxes(app, signal)
            
            app.SignalLine.YData = signal;
            
        end
        
        function refreshTrialNumberLabel(app, count)
            
            app.TrialNumberLabel.Text = num2str(count);
            
        end

        function refreshInfoLabel(app, text)

            app.InfoLabel.Text = text;

        end
        
        
        function refreshLamp(app, color)

            switch color

                case 'r'

                    app.Lamp.Color = [1 0 0];

                case 'y'

                    app.Lamp.Color = [1 1 0];

                case 'g'

                    app.Lamp.Color = [0 1 0];
                    
                case 'p'
                    
                    app.Lamp.Color = [0.5 0 0.5];
            end


        end

        function executeCountdown(app)

            app.CountdownLabel.Text = "3";
            pause(1.0);
            app.CountdownLabel.Text = "2";
            pause(1.0);
            app.CountdownLabel.Text = "1";
            pause(1.0);
            app.CountdownLabel.Text = "GO";
            pause(0.01);
            app.CountdownLabel.Text = "";

        end
        
        function setCurrentConfig(app)
            
            app.Tactx.Config.CURRENT_SLIDING_VELOCITY = app.Tactx.Config.SLIDING_VELOCITY(app.Counter);
            app.Tactx.Config.CURRENT_NORMAL_FORCE = app.Tactx.Config.NORMAL_FORCE(app.Counter);
            app.Tactx.Config.CURRENT_SIGNAL = app.Tactx.Config.SIGNAL(app.Counter);
            app.Tactx.Config.CURRENT_SIGNAL_PARAMETER_1 = app.Tactx.Config.SIGNAL_PARAMETER_1(app.Counter);
            app.Tactx.Config.CURRENT_SIGNAL_PARAMETER_2 = app.Tactx.Config.SIGNAL_PARAMETER_2(app.Counter);
            app.Tactx.Config.CURRENT_SLIDING = app.Counter;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.refreshInfoLabel("......");
            app.refreshLamp('r');

            % Fetch experiment-related configuration parameters
            initExperiment;
            CONFIG = generateConfigFile(CONFIG, 2, [1 1], 0.5, 4);
            
            % Configure TacTX object
            app.Tactx = TacTX('State', IdleState, 'Config', CONFIG);
            
            app.refreshInfoLabel("Connected to DAQ devices!");
            app.refreshLamp('y');
            
            app.SignalLine = plot(app.SignalAxes, 0);
            app.VelocityGauge.Position(3) = app.Tactx.Config.SLIDING_DISTANCE_IN_PIXELS;
            app.VelocityGauge.Position(1) = app.ForceGauge.Position(1) + app.ForceGauge.Position(3) / 2 - app.Tactx.Config.SLIDING_DISTANCE_IN_PIXELS / 2; 


            app.Counter = 1;
            app.refreshTrialNumberLabel(app.Counter);
            

            app.Signal = app.Tactx.Config.SIGNAL{app.Counter};
            app.refreshSignalAxes(app.Signal);
            app.Tactx.SignalGenerator.Signal = app.Signal;
            
            app.refreshForceGauge(app.Tactx.Config.NORMAL_FORCE(app.Counter));
            
            app.refreshInfoLabel("Biasing force sensor!");
            app.refreshLamp('p');
            
            app.Tactx.biasForceSensor();
            
            app.Tactx.NIHandler.ScansAvailableFunction = @(src, evt) app.scansAvailableFunction(src, evt);

            app.refreshInfoLabel("Ready to GO!");
            app.refreshLamp('y');


        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            app.executeCountdown();
            
            app.Tactx.run();
            app.refreshLamp('g');

            app.refreshInfoLabel("Data Acquisition started!");

            pause(app.Tactx.Config.SLIDING_DISTANCE / app.Tactx.Config.SLIDING_VELOCITY(app.Counter) + 0.1);

            app.Tactx.idle();
            app.refreshLamp('y');
            app.refreshInfoLabel("Data Acquisition finished!");

            pause(3.0);

            app.VelocityGauge.Value = 0;
            app.ForceGauge.Value = 0;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.refreshInfoLabel("Data saving started!");

            app.Tactx.idle();
            
            app.refreshLamp('r');
            
            app.setCurrentConfig();
            datestr_ = strrep(strrep(strrep(datestr(datetime('now')), ' ', ''), '-', ''), ':', '');
            app.Tactx.save(['deneme_' num2str(app.Counter) '_' num2str(app.Tactx.Config.NORMAL_FORCE(app.Counter)) '_' num2str(app.Tactx.Config.SLIDING_VELOCITY(app.Counter)) '_' datestr_ 'GOOD.mat']);
            
            pause(1.0)
            
            app.refreshInfoLabel("Data saving finished!");

            app.Tactx.idle();
            
            app.refreshLamp('y');
            
            if app.Counter == app.Tactx.Config.NUMBER_OF_SLIDINGS
               
                app.refreshInfoLabel("EXPERIMENT IS DONE!");
                pause(2.0);
                app.delete();
                
            else
                
                app.Counter = app.Counter + 1;
            
                app.refreshCounterDependentProperties();
                
            end
            
        end

        % Button pushed function: SAVEButton_2
        function SAVEButton_2Pushed(app, event)
            app.refreshInfoLabel("Data saving started!");

            app.Tactx.idle();
            
            app.refreshLamp('r');
            
            app.setCurrentConfig();
            datestr_ = strrep(strrep(strrep(datestr(datetime('now')), ' ', ''), '-', ''), ':', '');
            app.Tactx.save(['deneme_' num2str(app.Counter) '_' num2str(app.Tactx.Config.NORMAL_FORCE(app.Counter)) '_' num2str(app.Tactx.Config.SLIDING_VELOCITY(app.Counter)) '_' datestr_ 'BAD.mat']);
            
            pause(1.0)
            
            app.refreshInfoLabel("Data saving finished!");

            app.Tactx.idle();
            
            app.refreshLamp('y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceGauge = uigauge(app.UIFigure, 'semicircular');
            app.ForceGauge.Limits = [0 1];
            app.VelocityGauge = uigauge(app.UIFigure, 'linear');
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.Lamp = uilamp(app.UIFigure);
            app.SAVEButton_2 = uibutton(app.UIFigure, 'push');
            app.SAVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_2Pushed, true);
            app.SAVEButton_2.Text = 'SAVE';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial';
            app.TrialNumberLabel = uilabel(app.UIFigure);
            app.TrialNumberLabel.Text = '1';
            app.CountdownLabel = uilabel(app.UIFigure);
            app.CountdownLabel.Text = '';
            app.SignalAxes = uiaxes(app.UIFigure);
            xlabel(app.SignalAxes, 'Datapoint')
            ylabel(app.SignalAxes, 'Signal')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end