classdef Experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InfoLabel                     matlab.ui.control.Label
        STARTEXPERIMENTButton         matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        TrialLabel                    matlab.ui.control.Label
        TrialNumberLabel              matlab.ui.control.Label
        CountdownLabel                matlab.ui.control.Label
        InwhichintervaldidyoufeelelectrovibrationLabel  matlab.ui.control.Label
        Press1or2thenpressEnterLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TextureNumberLabel            matlab.ui.control.Label
        TextureLabel                  matlab.ui.control.Label
        VelocityGauge                 matlab.ui.control.LinearGauge
        FingerImage                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        Signal % Description
        Signal_1
        Signal_2
        UserInput
        PreviousUserInput
        SignalLine % Description
        SignalLength
        Tactx % Description
        Length
        Speed
        Counter
        UserInputReceived % Description
        UserInputAllowed % Description
        SignalInterval
        ReversalCounter % Description
        StairCounter
        Decision
        ScaleFactor
        StepSize
    end
    
    methods (Access = private)
        
        
        function scansAvailableFunction(app, src, evt)

            % Actual callback
            app.Tactx.scansAvailableFunction(src, evt);

            app.VelocityGauge.Value = app.VelocityGauge.Value + (app.VelocityGauge.Limits(2)-app.VelocityGauge.Limits(1))/( (app.Tactx.NIHandler.Rate / app.Tactx.Config.SCANS_AVAILABLE_FUNCTION_COUNT) * app.Tactx.Config.SLIDING_DISTANCE / app.Tactx.Config.SLIDING_VELOCITY);
        end
        
        
        function refreshCounterDependentProperties(app)
            
            app.refreshTrialNumberLabel(app.Counter);
                        
            app.SignalInterval = randi(2);
            
            if app.SignalInterval == 1
                
                app.Signal_1 = app.Signal * app.ScaleFactor;
                app.Signal_2 = zeros(size(app.Signal),'like',app.Signal);
                
            else
                
                app.Signal_1 = zeros(size(app.Signal),'like',app.Signal);
                app.Signal_2 = app.Signal * app.ScaleFactor;

            end
            
            
        end


        function refreshTrialNumberLabel(app, count)
            
            app.TrialNumberLabel.Text = num2str(count);
            
        end
        
        function refreshTextureNumberLabel(app, count)
            
            app.TextureNumberLabel.Text = num2str(count);
            
        end
        
        function refreshInfoLabel(app, text)
            
            app.InfoLabel.Text = text;
            
        end
        
        
        function refreshLamp(app, color)
            
            switch color
                
                case 'r'
                    
                    app.Lamp.Color = [1 0 0];
                    
                case 'y'
                    
                    app.Lamp.Color = [1 1 0];
                    
                case 'g'
                    
                    app.Lamp.Color = [0 1 0];
                    
                case 'p'
                    
                    app.Lamp.Color = [0.5 0 0.5];
            end
            
            
        end
        
        function executeCountdown(app)
            
            %             app.CountdownLabel.Text = "3";
            %             pause(1.0);
            app.CountdownLabel.Text = "2";
            pause(1.0);
            app.CountdownLabel.Text = "1";
            pause(1.0);
            app.CountdownLabel.Text = "GO";
            pause(0.01);
            app.CountdownLabel.Text = "";
            
        end
        
        function setCurrentConfig(app)
            
            app.Tactx.Config.CURRENT_SIGNAL_1 = app.Signal_1;
            
            app.Tactx.Config.CURRENT_SIGNAL_2 = app.Signal_2;
            
            app.Tactx.Config.CURRENT_SIGNAL_INTERVAL = app.SignalInterval;
            
            app.Tactx.Config.CURRENT_SLIDING = app.Counter;
            
            app.Tactx.Config.CURRENT_REVERSAL = app.ReversalCounter;
            
            app.Tactx.Config.CURRENT_USER_INPUT = app.UserInput;
            
            app.Tactx.Config.CURRENT_SCALE_FACTOR = app.ScaleFactor;
            
            app.Tactx.Config.CURRENT_STEP_SIZE = app.StepSize;
            
        end
        
        function saveData(app)
            
            if app.Counter ~= 1
                
                app.PreviousUserInput = app.Tactx.Config.CURRENT_USER_INPUT;
                
            end
            
            app.StairCounter = app.StairCounter + 1;
            
            
            app.refreshInfoLabel("Data saving started!");
            
            app.Tactx.idle();
            
            app.refreshLamp('r');
            
            app.setCurrentConfig();
            
            datestr_ = strrep(strrep(strrep(datestr(datetime('now')), ' ', ''), '-', ''), ':', '');
            
            app.Tactx.save(['experiment_trial' num2str(app.Counter) '_' datestr_ '.mat']);
            
            pause(1.0)
            
            app.refreshInfoLabel("Data saving finished!");
            
            app.Tactx.idle();
            
            app.refreshLamp('y');


            if app.ReversalCounter == app.Tactx.Config.MAX_REVERSAL
                
                app.refreshInfoLabel("EXPERIMENT IS DONE!");
                pause(2.0);
                app.delete();
                
            elseif app.Counter == 1
                
                app.Decision = 0;
                
            else
                
                switch app.StairCounter
                    
                    case app.Tactx.Config.UPSTAIR
                        
                        if app.UserInput ~= app.SignalInterval
                            
                            app.Decision = +1;
                            app.StairCounter = 0;
                            
                        else
                            
                            app.Decision = 0;
                            
                        end
                        
                        
                    case app.Tactx.Config.DOWNSTAIR
                        
                        if app.UserInput == app.SignalInterval
                            
                            app.Decision = -1;
                            
                        else
                            
                            app.Decision = +1;                            
                            
                        end
                        
                        app.StairCounter = 0;
                        
                    otherwise
                        
                        if app.UserInput == 0
                            
                            app.Decision = +1;
                            app.StairCounter = 0;
                            
                        else
                            
                            app.Decision = 0;
                            
                        end
                end
                
                
                if app.UserInput ~= app.PreviousUserInput
                    
                    app.ReversalCounter = app.ReversalCounter + 1;
                    
                end
                
            end
            
            if app.ReversalCounter <= app.Tactx.Config.REVERSAL_RULE_1
                
                app.StepSize = app.Tactx.Config.STEP_SIZE_1;
                
            elseif app.ReversalCounter <= app.Tactx.Config.REVERSAL_RULE_2
                
                app.StepSize = app.Tactx.Config.STEP_SIZE_2;
                
            elseif app.ReversalCounter <= app.Tactx.Config.REVERSAL_RULE_3
                
                app.StepSize = app.Tactx.Config.STEP_SIZE_3;
                
            end
            
            app.ScaleFactor = app.ScaleFactor + app.Decision * app.StepSize;
            
            app.Counter = app.Counter + 1;
            
            app.refreshCounterDependentProperties();


        end
        
        function oneTrialExperiment(app)
            app.UserInputAllowed = false;
            app.UserInputReceived = false;
            
            app.FingerImage.Visible = true;
            app.refreshInfoLabel("PLEASE PUT YOUR FINGER TO THE LEFT EDGE OF THE SLIDING REGION!")
            pause(2.0);
            
            app.executeCountdown();
            
            app.Tactx.SignalGenerator.Signal = app.Signal_1;
            app.refreshInfoLabel("Explore the 1st texture!");
            app.FingerImage.Visible = false;
            app.refreshTextureNumberLabel(1);
            app.refreshLamp('g');
            app.Tactx.run('RepeatOutput');
            
            
            pause(app.Tactx.Config.EXPLORATION_DURATION);
            
            app.Tactx.idle();
            app.refreshLamp('y');
            app.refreshInfoLabel("Finish!");
            app.VelocityGauge.Value = 0;
            
            app.FingerImage.Visible = true;
            app.refreshInfoLabel("PLEASE PUT YOUR FINGER TO THE LEFT EDGE OF THE SLIDING REGION!")
            pause(2.0);
            app.refreshInfoLabel("Wait for 1 second!");
            pause(1.0);
            
            
            app.executeCountdown();

            app.Tactx.SignalGenerator.Signal = app.Signal_2;
            app.refreshInfoLabel("Explore the 2nd texture!");
            app.FingerImage.Visible = false;
            app.refreshTextureNumberLabel(2);
            app.refreshLamp('g');
            app.Tactx.run('RepeatOutput');
            
            pause(app.Tactx.Config.EXPLORATION_DURATION);
            
            app.Tactx.idle();
            app.refreshLamp('y');
            app.refreshInfoLabel("Finish!");
            app.VelocityGauge.Value = 0;

            app.openUserInputComponents();
            app.UserInputAllowed = true;
            app.refreshInfoLabel("Waiting for user input!");
            app.refreshTextureNumberLabel("");
            
        end
        
        
        function closeUserInputComponents(app)
            
            app.InwhichintervaldidyoufeelelectrovibrationLabel.Visible = false;
            app.Press1or2thenpressEnterLabel.Visible = false;
            app.Label.Text = "";
            
            
        end
        
        function openUserInputComponents(app)
            
            app.InwhichintervaldidyoufeelelectrovibrationLabel.Visible = true;
            app.Press1or2thenpressEnterLabel.Visible = true;
            app.Label.Text = "";
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UserInputReceived = false;
            app.UserInputAllowed = false;
            
            app.closeUserInputComponents();
            
            app.refreshInfoLabel("......");
            app.refreshLamp('r');
            
            % Fetch experiment-related configuration parameters
            initExperiment;
            CONFIG = generateConfigFile(CONFIG);
            
            % Configure TacTX object
            app.Tactx = TacTX('State', IdleState, 'Config', CONFIG);
            
            app.refreshInfoLabel("Connected to DAQ devices!");
            app.refreshLamp('y');
            
            pause(1.0);

            app.VelocityGauge.Position(3) = app.Tactx.Config.SLIDING_DISTANCE_IN_PIXELS;
            app.VelocityGauge.Position(1) = app.STARTEXPERIMENTButton.Position(1) + app.STARTEXPERIMENTButton.Position(3) / 2 - app.Tactx.Config.SLIDING_DISTANCE_IN_PIXELS / 2; 
            
            app.FingerImage.Position(1) = app.VelocityGauge.Position(1) - app.FingerImage.Position(3) * 0.5 + 17 ;
            app.FingerImage.Position(2) = app.VelocityGauge.Position(2) - app.FingerImage.Position(4);


            app.Counter = 1;
            app.ReversalCounter = 1;
            app.StairCounter = 0;


            app.Signal = app.Tactx.Config.SIGNAL;


            app.ScaleFactor = app.Tactx.Config.INITIAL_SCALE_FACTOR;
            
            app.refreshCounterDependentProperties();
            
            
            app.Tactx.SignalGenerator.Signal = app.Signal;

            app.refreshInfoLabel("Biasing force sensor!");
            app.refreshLamp('p');
            
            app.Tactx.biasForceSensor();
            app.Tactx.NIHandler.ScansAvailableFunction = @(src, evt) app.scansAvailableFunction(src, evt);

            
            app.refreshInfoLabel("Ready to GO!");
            app.refreshLamp('y');


        end

        % Button pushed function: STARTEXPERIMENTButton
        function STARTEXPERIMENTButtonPushed(app, event)
            
            app.STARTEXPERIMENTButton.Enable = false;
            pause(0.5);
            app.oneTrialExperiment();
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            
            if app.UserInputAllowed
                
                switch key
                    
                    case 'numpad1'
                        
                        %if ~app.UserInputReceived
                            app.UserInput = 1;
                            app.Label.Text = "1";
                            app.UserInputReceived = true;
                        %end
                    case 'numpad2'
                        %if ~app.UserInputReceived
                            
                            app.UserInput = 2;
                            app.Label.Text = "2";
                            app.UserInputReceived = true;
                        %end
                    case 'return'
                        
                        if app.UserInputReceived
                            
                            app.UserInputAllowed = false;
                            
                            app.closeUserInputComponents();
                            app.saveData();
                            pause(1.0);
                            app.oneTrialExperiment();
                            
                        end


                    otherwise
                        
                        app.refreshInfoLabel("Wrong key!");
                        pause(1.0);
                        app.refreshInfoLabel("Waiting for user input!");
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '';
            app.STARTEXPERIMENTButton = uibutton(app.UIFigure, 'push');
            app.STARTEXPERIMENTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTEXPERIMENTButtonPushed, true);
            app.STARTEXPERIMENTButton.Text = 'START EXPERIMENT';
            app.Lamp = uilamp(app.UIFigure);
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial';
            app.TrialNumberLabel = uilabel(app.UIFigure);
            app.TrialNumberLabel.Text = '1';
            app.CountdownLabel = uilabel(app.UIFigure);
            app.CountdownLabel.Text = '';
            app.InwhichintervaldidyoufeelelectrovibrationLabel = uilabel(app.UIFigure);
            app.InwhichintervaldidyoufeelelectrovibrationLabel.Text = 'In which interval did you feel electrovibration?';
            app.Press1or2thenpressEnterLabel = uilabel(app.UIFigure);
            app.Press1or2thenpressEnterLabel.Text = {'Press 1 or 2, '; 'then press Enter'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextureNumberLabel = uilabel(app.UIFigure);
            app.TextureNumberLabel.Text = '1';
            app.TextureLabel = uilabel(app.UIFigure);
            app.TextureLabel.Text = 'Texture';
            app.VelocityGauge = uigauge(app.UIFigure, 'linear');
            app.FingerImage = uiimage(app.UIFigure);
            app.FingerImage.ImageSource = 'finger.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end