classdef ForcePractice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForcePracticeUIFigure  matlab.ui.Figure
        Label                  matlab.ui.control.Label
        SAVEButton             matlab.ui.control.Button
        IDLEButton             matlab.ui.control.Button
        RUNButton              matlab.ui.control.Button
        Gauge                  matlab.ui.control.SemicircularGauge
        Gauge_2                matlab.ui.control.LinearGauge
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        CLOSEButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        Tactx % Description
    end
    
    properties (Access = private)
        PracticeCounter % Description
        PracticeTypeCounter % Description
        PracticeText % Description
    end
    
    methods (Access = private)
        
        function scansAvailableFunction(app, src, evt)
            
            % Actual callback
            app.Tactx.scansAvailableFunction(src, evt);
            
            if length(app.Tactx.ForceSensor.ForceTorque) > 100
                
                app.Gauge.Value = abs(mean(app.Tactx.ForceSensor.ForceTorque(end-50:end, 3)));
                
            else
                
                app.Gauge.Value = 0;
                
            end
            
            if app.Gauge.Value >= 0.45 && app.Gauge.Value <= 0.55
                
                app.Gauge_2.Value = app.Gauge_2.Value + 1;
                app.Gauge_2.ScaleColorLimits(2) = app.Gauge_2.ScaleColorLimits(2) + 1;
                
            end
            
            if app.Gauge_2.Value > 100
                
                app.PracticeCounter = app.PracticeCounter + 1;
                app.Label_2.Text = num2str(app.PracticeCounter);
                app.Gauge_2.Value = 0;
                app.Gauge_2.ScaleColorLimits(2) = 1e-3;
                app.Gauge.Value = 0;
                
                app.Tactx.idle();
                app.Label.Text = "System is IDLE!";

                app.Tactx.save(['Practice_' num2str(app.PracticeCounter) '-' num2str(app.PracticeTypeCounter)  '.mat']);
                app.Label.Text = ['Data is saved as ' 'Practice_' num2str(app.PracticeCounter) '-' num2str(app.PracticeTypeCounter)  '.mat'];

                app.Tactx.idle();
                app.Label.Text = "System is IDLE!";


            end
            
            if app.PracticeCounter == app.Tactx.Config.PRACTICE_MODE_NUMBER_OF_FORCE_PRACTICES
                
                app.PracticeCounter = 0;
                
                app.PracticeTypeCounter = app.PracticeTypeCounter + 1;
                
            end
            
            if app.PracticeTypeCounter > length(app.PracticeText)
                
                app.RUNButton.Enable = false;
                app.IDLEButton.Enable = false;
                app.SAVEButton.Enable = false;
                app.CLOSEButton.Visible = true;
                
            else
                
                
                app.Label_3.Text = app.PracticeText{app.PracticeTypeCounter};
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % To make fullscreen seamlessly
            pause(2.0);
            app.ForcePracticeUIFigure.WindowState = "maximized";
            drawnow;
            app.ForcePracticeUIFigure.WindowState = "fullscreen";
            drawnow;
            
            % Practice-related param init
            app.PracticeCounter = 0;
            app.PracticeTypeCounter = 1;
            app.PracticeText = {"Just press one point to reach 0.5 N",...
                "Try to reach 0.5N while sliding your finger"};
            app.Label_3.Text = app.PracticeText{app.PracticeTypeCounter};
            
            app.Label.Text = "......";
            init;
            app.Tactx = TacTX('State', IdleState, 'Config', CONFIG);
            app.Tactx.NIHandler.ScansAvailableFunction = @(src, evt) app.scansAvailableFunction(src, evt);
            app.Label.Text = "Connected to DAQ devices!";
            
            
        end

        % Close request function: ForcePracticeUIFigure
        function ForcePracticeUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.Tactx.run();
            app.Label.Text = "Data Acquisition started!";
            disp(app.Tactx.State);
        end

        % Button pushed function: IDLEButton
        function IDLEButtonPushed(app, event)
            app.Tactx.idle();
            app.Label.Text = "System is IDLE!";
            disp(app.Tactx.State);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.Tactx.save("Data.mat");
            app.Label.Text = "Data is saved!";
            disp(app.Tactx.State);
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForcePracticeUIFigure = uifigure('Visible', 'off');
            app.ForcePracticeUIFigure.Name = 'Force Practice';
            app.ForcePracticeUIFigure.CloseRequestFcn = createCallbackFcn(app, @ForcePracticeUIFigureCloseRequest, true);
            app.Label = uilabel(app.ForcePracticeUIFigure);
            app.Label.Text = '';
            app.SAVEButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.IDLEButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.IDLEButton.ButtonPushedFcn = createCallbackFcn(app, @IDLEButtonPushed, true);
            app.IDLEButton.Text = 'IDLE';
            app.RUNButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Gauge = uigauge(app.ForcePracticeUIFigure, 'semicircular');
            app.Gauge.Limits = [0 1];
            app.Gauge.MajorTicks = [0 0.1 0.2 0.3 0.4 0.45 0.5 0.55 0.6 0.7 0.8 0.9 1];
            app.Gauge_2 = uigauge(app.ForcePracticeUIFigure, 'linear');
            app.Gauge_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Label_2 = uilabel(app.ForcePracticeUIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.ForcePracticeUIFigure);
            app.Label_3.Text = '';
            app.CLOSEButton = uibutton(app.ForcePracticeUIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Visible = 'off';
            app.CLOSEButton.Text = 'CLOSE';
            % Show the figure after all components are created
            app.ForcePracticeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForcePractice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForcePracticeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForcePracticeUIFigure)
        end
    end
end