classdef VelocityPractice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VelocityPracticeUIFigure  matlab.ui.Figure
        Label                     matlab.ui.control.Label
        CLOSEButton               matlab.ui.control.Button
        Label_4                   matlab.ui.control.Label
        Gauge                     matlab.ui.control.LinearGauge
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        PressNtostartpracticesessionLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Tactx % Description
    end
    
    properties (Access = private)
        PracticeCounter % Description
        Timer % Description
        Speed % Description
        Length % Description
        Rate % Description
    end
    
    methods (Access = private)


        function TimerCallback(app, ~, ~)
            
            app.Gauge.Value = app.Gauge.Value + (app.Gauge.Limits(2)-app.Gauge.Limits(1))/( app.Rate * app.Length / app.Speed(app.PracticeCounter));
            
            if app.Gauge.Value > app.Gauge.Limits(2)
                
                app.Tactx.idle();
                stop(app.Timer);
                
                fingerPosition = app.Tactx.FingerTracker.FingerPosition;
                app.Tactx.FingerTracker.FingerPosition = [];
                fingerVelocity = diff(fingerPosition(:, 1)) * 1440 * 0.02822222222 * app.Tactx.MISCHandler.Rate;
                meanFingerVelocity = mean(fingerVelocity);
                app.Label_2.Text = ['Calculated Speed = ' num2str(meanFingerVelocity) ' cm/s'];
                save([num2str(app.PracticeCounter) '.mat'], "fingerVelocity", "fingerPosition", "meanFingerVelocity");
                
                app.PressNtostartpracticesessionLabel.Visible = true;
                app.Gauge.Value = 0;
                
                app.PracticeCounter = app.PracticeCounter + 1;

            
            end
            
            
            if app.PracticeCounter > length(app.Speed)
                
                
                app.Tactx.idle();
                app.CLOSEButton.Visible = true;


            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
%             % To make fullscreen seamlessly
%             pause(2.0);
%             app.VelocityPracticeUIFigure.WindowState = "maximized";
%             drawnow;
%             app.VelocityPracticeUIFigure.WindowState = "fullscreen";
%             drawnow;
            
%             app.Gauge.Position = [720 450 390 100];
            
            
            app.Length = 11;
            app.Speed = [2, 2.5, 3, 3.5, 4, 5, 5.5];
            
            app.Rate = 10;
            
            app.Timer = timer('TimerFcn', @(~, ~) app.TimerCallback, ...
                'StartFcn', '', ...
                'StopFcn', '', ...
                'Period', 1 / app.Rate, ...
                'StartDelay', 0, ...
                'BusyMode', 'queue', ...
                'ExecutionMode', 'fixedRate');
            
            % Practice-related param init
            app.PracticeCounter = 1;
            
            app.Label.Text = "......";
            initPractice;
            app.Tactx = TacTX('State', IdleState, 'Config', CONFIG);
            app.Label.Text = "Connected to DAQ devices!";
            app.PressNtostartpracticesessionLabel.Visible = true;
            
            
        end

        % Close request function: VelocityPracticeUIFigure
        function VelocityPracticeUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Callback function
        function RUNButtonPushed(app, event)
            app.Tactx.run();
            start(app.Timer);
            app.Label.Text = "Data Acquisition started!";
            disp(app.Tactx.State);
        end

        % Callback function
        function IDLEButtonPushed(app, event)
            app.Tactx.idle();
            app.Label.Text = "System is IDLE!";
            disp(app.Tactx.State);
        end

        % Callback function
        function SAVEButtonPushed(app, event)
            app.Tactx.save("Data.mat");
            app.Label.Text = "Data is saved!";
            disp(app.Tactx.State);
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            delete(app);
        end

        % Key press function: VelocityPracticeUIFigure
        function VelocityPracticeUIFigureKeyPress(app, event)
            
            key = event.Key;
            if key == 'n'
                
                app.PressNtostartpracticesessionLabel.Visible = false;
                app.Label_2.Text = "";
                app.Tactx.run();
                start(app.Timer);
                app.Label.Text = "Data Acquisition started!";
                app.Label_3.Text = ['Desired Speed = ' num2str(app.Speed(app.PracticeCounter)) ' cm/s'];
                disp(app.Tactx.State);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VelocityPracticeUIFigure = uifigure('Visible', 'off');
            app.VelocityPracticeUIFigure.Name = 'Velocity Practice';
            app.VelocityPracticeUIFigure.CloseRequestFcn = createCallbackFcn(app, @VelocityPracticeUIFigureCloseRequest, true);
            app.VelocityPracticeUIFigure.KeyPressFcn = createCallbackFcn(app, @VelocityPracticeUIFigureKeyPress, true);
            app.Label = uilabel(app.VelocityPracticeUIFigure);
            app.Label.Text = '';
            app.CLOSEButton = uibutton(app.VelocityPracticeUIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Visible = 'off';
            app.CLOSEButton.Text = 'CLOSE';
            app.Label_4 = uilabel(app.VelocityPracticeUIFigure);
            app.Label_4.Text = '---';
            app.Gauge = uigauge(app.VelocityPracticeUIFigure, 'linear');
            app.Label_2 = uilabel(app.VelocityPracticeUIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.VelocityPracticeUIFigure);
            app.Label_3.Text = '';
            app.PressNtostartpracticesessionLabel = uilabel(app.VelocityPracticeUIFigure);
            app.PressNtostartpracticesessionLabel.Visible = 'off';
            app.PressNtostartpracticesessionLabel.Text = 'Press "N" to start practice session';
            % Show the figure after all components are created
            app.VelocityPracticeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VelocityPractice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VelocityPracticeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VelocityPracticeUIFigure)
        end
    end
end