classdef RandomSignalGenerationandTransmission < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ForceGauge      matlab.ui.control.SemicircularGauge
        Gauge_2Label    matlab.ui.control.Label
        VelocityGauge   matlab.ui.control.LinearGauge
        InfoLabel       matlab.ui.control.Label
        GENERATEButton  matlab.ui.control.Button
        EMITButton      matlab.ui.control.Button
        SAVEButton      matlab.ui.control.Button
        EditField       matlab.ui.control.NumericEditField
        SignalAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Signal % Description
        SignalLine % Description
        TacTX % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initRandomMeasurement;
            app.TacTX = TacTX('Stete', IdleState, 'Config', CONFIG);


            app.SignalLine = plot(app.SignalAxes, 0);
            app.Signal = idinput([100000, 1], 'prbs', [0 1/5], [-3 3]);

        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            app.EditField.Value
            app.SignalLine.YData = app.Signal(app.EditField.Value*1000 + 1 : (app.EditField.Value+1)*1000 + 1);
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceGauge = uigauge(app.UIFigure, 'semicircular');
            app.ForceGauge.Limits = [0 1];
            app.ForceGauge.MajorTicks = [0 0.1 0.2 0.3 0.4 0.45 0.5 0.55 0.6 0.7 0.8 0.9 1];
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = '';
            app.VelocityGauge = uigauge(app.UIFigure, 'linear');
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '';
            app.GENERATEButton = uibutton(app.UIFigure, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.EMITButton = uibutton(app.UIFigure, 'push');
            app.EMITButton.Text = 'EMIT';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalAxes = uiaxes(app.UIFigure);
            xlabel(app.SignalAxes, 'Datapoint')
            ylabel(app.SignalAxes, 'Signal')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RandomSignalGenerationandTransmission

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end